classdef SetAnalysisSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout_TitleRow             matlab.ui.container.GridLayout
        TitleLabel                      matlab.ui.control.Label
        LoadexistingsettingsButton      matlab.ui.control.Button
        MainPanel_Location              matlab.ui.container.Panel
        GridLayout4_2                   matlab.ui.container.GridLayout
        DoLocationCheckBox              matlab.ui.control.CheckBox
        LocationSetButton               matlab.ui.control.Button
        LocationCheckButton             matlab.ui.control.Button
        MainPanel_EyeVideo              matlab.ui.container.Panel
        GridLayout4_3                   matlab.ui.container.GridLayout
        EyeVideo_DoCheckBox             matlab.ui.control.CheckBox
        EyeVideo_InvertCheckBox         matlab.ui.control.CheckBox
        EyeVideo_DeflickerCheckBox      matlab.ui.control.CheckBox
        MainPanel_LineShift             matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout9                     matlab.ui.container.GridLayout
        LineShift_DoCheckBox            matlab.ui.control.CheckBox
        LineShift_DirectionButtonGroup  matlab.ui.container.ButtonGroup
        LineShift_DirectionVerticalButton  matlab.ui.control.RadioButton
        LineShift_DirectionHorizontalButton  matlab.ui.control.RadioButton
        GridLayout8                     matlab.ui.container.GridLayout
        LineShift_DetectButton          matlab.ui.control.Button
        LineShift_shiftValue            matlab.ui.container.GridLayout
        LineShift_shiftValueSpinner     matlab.ui.control.Spinner
        shiftSpinnerLabel               matlab.ui.control.Label
        UIAxes_LineShift                matlab.ui.control.UIAxes
        MainPanel_NoRMCorre             matlab.ui.container.Panel
        GridLayout_MainNoRMCorre        matlab.ui.container.GridLayout
        NoRMCorre_DoCheckBox            matlab.ui.control.CheckBox
        GridLayout10                    matlab.ui.container.GridLayout
        NoRMCorre_methodDropDown        matlab.ui.control.DropDown
        methodDropDownLabel             matlab.ui.control.Label
        NoRMCorre_CropxPanel            matlab.ui.container.Panel
        GridLayout_NoRMCorreX           matlab.ui.container.GridLayout
        xstartleftEditField             matlab.ui.control.NumericEditField
        xstartleftEditFieldLabel        matlab.ui.control.Label
        xendEditField                   matlab.ui.control.NumericEditField
        xendEditFieldLabel              matlab.ui.control.Label
        NoRMCorre_CropyPanel            matlab.ui.container.Panel
        GridLayout_NoRMCorreY           matlab.ui.container.GridLayout
        yendEditField                   matlab.ui.control.NumericEditField
        yendEditFieldLabel              matlab.ui.control.Label
        ystarttopEditField              matlab.ui.control.NumericEditField
        ystarttopEditFieldLabel         matlab.ui.control.Label
        UIAxes_NoRMCorreCrop            matlab.ui.control.UIAxes
        MainPanel_BackgroundSub         matlab.ui.container.Panel
        GridLayout_MainBackgroundSub    matlab.ui.container.GridLayout
        BackgroundSub_StartUIButton     matlab.ui.control.Button
        BackgroundSub_DoCheckBox        matlab.ui.control.CheckBox
        SmoothingsizeEditFieldLabel     matlab.ui.control.Label
        GaussianSmoothingLabel          matlab.ui.control.Label
        BackgroundSub_FilterTypeDropDown  matlab.ui.control.DropDown
        FiltertypeDropDownLabel         matlab.ui.control.Label
        BackgroundSub_FilterRadiusEditField  matlab.ui.control.NumericEditField
        BackgroundSub_ValueShifterEditField  matlab.ui.control.NumericEditField
        ValueshifterEditFieldLabel      matlab.ui.control.Label
        FilterradiusEditFieldLabel      matlab.ui.control.Label
        BackgroundSub_GaussianSmoothingDropDown  matlab.ui.control.DropDown
        BackgroundSub_SmoothingsizeEditField  matlab.ui.control.NumericEditField
        BackgroundSub_plotterCheckBox   matlab.ui.control.CheckBox
        MainPanel_GetRois               matlab.ui.container.Panel
        GridLayout4_5                   matlab.ui.container.GridLayout
        doRoi                           matlab.ui.control.CheckBox
        doRoi_loadSparButton            matlab.ui.control.Button
        doRoi_SetButton                 matlab.ui.control.Button
        doRoi_SparStatusLabel           matlab.ui.control.Label
        MainPanel_timedData             matlab.ui.container.Panel
        GridLayout_MainTimedData        matlab.ui.container.GridLayout
        timedFile_DoCheckBox            matlab.ui.control.CheckBox
        timedFile_SelectButton          matlab.ui.control.Button
        GridLayout_StartButtons         matlab.ui.container.GridLayout
        SettingsToworkspaceButton       matlab.ui.control.Button
        STARTButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        spar struct % Spectral PArameters/ Search PARameters for ROI detection
        do   struct % All other settings for AutomatedAnalysis
    end
    
    methods (Access = public)
        function CollectDo(app)
            app.do(1).LineShift     = app.LineShift_DoCheckBox;
            app.do.LineShiftTrans   = app.LineShift_DirectionVerticalButton.Value;
            app.do.LineShiftValue   = app.LineShift_shiftValueSpinner.Value;
            app.do.NoRMCorre        = app.NoRMCorre_DoCheckBox.Value;
            app.do.alignMethod      = app.NoRMCorre_methodDropDown.Value;
            % app.do.  = app.
            app.do.eyeConvert  = app.EyeVideo_DoCheckBox.Value;
            app.do.EyeInvert  = app.EyeVideo_InvertCheckBox.Value;
            app.do.EyeDeFlicker  = app.EyeVideo_DeflickerCheckBox.Value;
            % app.do.  = app.
            app.do.BackgroundSubtract = app.BackgroundSub_DoCheckBox.Value;
            app.do.BackgroundSub.FilterRadius  = app.BackgroundSub_FilterRadiusEditField.Value;
            app.do.BackgroundSub.filterMethod  = app.BackgroundSub_FilterTypeDropDown.Value;
            app.do.BackgroundSub.SmoothDim  = app.BackgroundSub_GaussianSmoothingDropDown.ValueIndex - 1;
            app.do.BackgroundSub.SmoothSe  = app.BackgroundSub_SmoothingsizeEditField.Value;
            app.do.BackgroundSub.plotter = app.BackgroundSub_plotterCheckBox.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: doRoi_SetButton
        function doRoi_SetButtonPushed(app, event)
           app.spar = Spectroiparm(app.spar);
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            CollectDo(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout_StartButtons = uigridlayout(app.GridLayout);
            app.STARTButton = uibutton(app.GridLayout_StartButtons, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Tooltip = {'GO'};
            app.STARTButton.Text = 'START';
            app.SettingsToworkspaceButton = uibutton(app.GridLayout_StartButtons, 'push');
            app.SettingsToworkspaceButton.Tooltip = {'If you save the settings, called ''do'', to workspace, you can safely and easily execute specific sections of AutomatedAnalysis'};
            app.SettingsToworkspaceButton.Text = {'Settings'; 'To workspace'};
            app.MainPanel_timedData = uipanel(app.GridLayout);
            app.MainPanel_timedData.Title = 'Save analysis log / timing info';
            app.GridLayout_MainTimedData = uigridlayout(app.MainPanel_timedData);
            app.timedFile_SelectButton = uibutton(app.GridLayout_MainTimedData, 'push');
            app.timedFile_SelectButton.Text = 'Select file';
            app.timedFile_DoCheckBox = uicheckbox(app.GridLayout_MainTimedData);
            app.timedFile_DoCheckBox.Text = 'Save time info';
            app.MainPanel_GetRois = uipanel(app.GridLayout);
            app.MainPanel_GetRois.Title = 'Detect ROIs';
            app.GridLayout4_5 = uigridlayout(app.MainPanel_GetRois);
            app.doRoi_SparStatusLabel = uilabel(app.GridLayout4_5);
            app.doRoi_SparStatusLabel.Tooltip = {'Spectral PARameters/ Search PARameters, for ROI detection'};
            app.doRoi_SparStatusLabel.Text = 'spar presence not checked';
            app.doRoi_SetButton = uibutton(app.GridLayout4_5, 'push');
            app.doRoi_SetButton.ButtonPushedFcn = createCallbackFcn(app, @doRoi_SetButtonPushed, true);
            app.doRoi_SetButton.Text = {'Set/ check '; 'ROI detection settings'};
            app.doRoi_loadSparButton = uibutton(app.GridLayout4_5, 'push');
            app.doRoi_loadSparButton.Text = {'Load ROI detection settings'; '(spar)'};
            app.doRoi = uicheckbox(app.GridLayout4_5);
            app.doRoi.Text = 'Do ROI detection';
            app.MainPanel_BackgroundSub = uipanel(app.GridLayout);
            app.MainPanel_BackgroundSub.Title = 'Background subtraction. For 1 photon or miniscope only';
            app.GridLayout_MainBackgroundSub = uigridlayout(app.MainPanel_BackgroundSub);
            app.BackgroundSub_plotterCheckBox = uicheckbox(app.GridLayout_MainBackgroundSub);
            app.BackgroundSub_plotterCheckBox.Text = {'plot last'; 'frame'};
            app.BackgroundSub_SmoothingsizeEditField = uieditfield(app.GridLayout_MainBackgroundSub, 'numeric');
            app.BackgroundSub_SmoothingsizeEditField.Tooltip = {'Standard deviation size of the one directional filtering gaussian '};
            app.BackgroundSub_GaussianSmoothingDropDown = uidropdown(app.GridLayout_MainBackgroundSub);
            app.BackgroundSub_GaussianSmoothingDropDown.Items = {'none', 'horizontal smoothing', 'vertical smoothing'};
            app.BackgroundSub_GaussianSmoothingDropDown.Tooltip = {'If extreme banding artifacts in one direction are present, the data might benefit from smoothing over the perpendicular direction.'};
            app.BackgroundSub_GaussianSmoothingDropDown.Value = 'none';
            app.FilterradiusEditFieldLabel = uilabel(app.GridLayout_MainBackgroundSub);
            app.FilterradiusEditFieldLabel.Text = 'Filter radius';
            app.ValueshifterEditFieldLabel = uilabel(app.GridLayout_MainBackgroundSub);
            app.ValueshifterEditFieldLabel.Text = 'Value shifter';
            app.BackgroundSub_ValueShifterEditField = uieditfield(app.GridLayout_MainBackgroundSub, 'numeric');
            app.BackgroundSub_ValueShifterEditField.Tooltip = {'By subtracting, values can go below 0. '; 'These values would be cut off at 0. Increasing the shifter value prevents this by summing this value to every pixel.'};
            app.BackgroundSub_FilterRadiusEditField = uieditfield(app.GridLayout_MainBackgroundSub, 'numeric');
            app.FiltertypeDropDownLabel = uilabel(app.GridLayout_MainBackgroundSub);
            app.FiltertypeDropDownLabel.Text = 'Filter type';
            app.BackgroundSub_FilterTypeDropDown = uidropdown(app.GridLayout_MainBackgroundSub);
            app.BackgroundSub_FilterTypeDropDown.Items = {'Circular Average', 'Gaussian Average'};
            app.BackgroundSub_FilterTypeDropDown.Value = 'Circular Average';
            app.GaussianSmoothingLabel = uilabel(app.GridLayout_MainBackgroundSub);
            app.GaussianSmoothingLabel.Text = {'Gaussian'; ' Smoothing'};
            app.SmoothingsizeEditFieldLabel = uilabel(app.GridLayout_MainBackgroundSub);
            app.SmoothingsizeEditFieldLabel.Text = 'Smoothing size';
            app.BackgroundSub_DoCheckBox = uicheckbox(app.GridLayout_MainBackgroundSub);
            app.BackgroundSub_DoCheckBox.Text = 'Do background subtraction';
            app.BackgroundSub_StartUIButton = uibutton(app.GridLayout_MainBackgroundSub, 'push');
            app.BackgroundSub_StartUIButton.Tooltip = {'Start separate GUI'};
            app.BackgroundSub_StartUIButton.Text = 'Check parameters with UI';
            app.MainPanel_NoRMCorre = uipanel(app.GridLayout);
            app.MainPanel_NoRMCorre.Title = 'NoRMCorre Motion correction';
            app.GridLayout_MainNoRMCorre = uigridlayout(app.MainPanel_NoRMCorre);
            app.UIAxes_NoRMCorreCrop = uiaxes(app.GridLayout_MainNoRMCorre);
            app.UIAxes_NoRMCorreCrop.XTick = [];
            app.UIAxes_NoRMCorreCrop.YTick = [];
            app.NoRMCorre_CropyPanel = uipanel(app.GridLayout_MainNoRMCorre);
            app.NoRMCorre_CropyPanel.Title = 'Crop y';
            app.GridLayout_NoRMCorreY = uigridlayout(app.NoRMCorre_CropyPanel);
            app.ystarttopEditFieldLabel = uilabel(app.GridLayout_NoRMCorreY);
            app.ystarttopEditFieldLabel.Text = 'y start (top)';
            app.ystarttopEditField = uieditfield(app.GridLayout_NoRMCorreY, 'numeric');
            app.yendEditFieldLabel = uilabel(app.GridLayout_NoRMCorreY);
            app.yendEditFieldLabel.Text = 'y end';
            app.yendEditField = uieditfield(app.GridLayout_NoRMCorreY, 'numeric');
            app.NoRMCorre_CropxPanel = uipanel(app.GridLayout_MainNoRMCorre);
            app.NoRMCorre_CropxPanel.Title = 'Crop x';
            app.GridLayout_NoRMCorreX = uigridlayout(app.NoRMCorre_CropxPanel);
            app.xendEditFieldLabel = uilabel(app.GridLayout_NoRMCorreX);
            app.xendEditFieldLabel.Text = 'x end';
            app.xendEditField = uieditfield(app.GridLayout_NoRMCorreX, 'numeric');
            app.xstartleftEditFieldLabel = uilabel(app.GridLayout_NoRMCorreX);
            app.xstartleftEditFieldLabel.Text = 'x start (left)';
            app.xstartleftEditField = uieditfield(app.GridLayout_NoRMCorreX, 'numeric');
            app.GridLayout10 = uigridlayout(app.GridLayout_MainNoRMCorre);
            app.methodDropDownLabel = uilabel(app.GridLayout10);
            app.methodDropDownLabel.Text = 'method';
            app.NoRMCorre_methodDropDown = uidropdown(app.GridLayout10);
            app.NoRMCorre_methodDropDown.Items = {'Rigid', 'Nonrigid'};
            app.NoRMCorre_methodDropDown.Value = 'Rigid';
            app.NoRMCorre_DoCheckBox = uicheckbox(app.GridLayout_MainNoRMCorre);
            app.NoRMCorre_DoCheckBox.Text = 'Do motion correction';
            app.NoRMCorre_DoCheckBox.Value = true;
            app.MainPanel_LineShift = uipanel(app.GridLayout);
            app.MainPanel_LineShift.Title = 'Line shift, to correct for bidirection scanning misalignment';
            app.GridLayout2 = uigridlayout(app.MainPanel_LineShift);
            app.UIAxes_LineShift = uiaxes(app.GridLayout2);
            app.GridLayout8 = uigridlayout(app.GridLayout2);
            app.LineShift_shiftValue = uigridlayout(app.GridLayout8);
            app.shiftSpinnerLabel = uilabel(app.LineShift_shiftValue);
            app.shiftSpinnerLabel.Text = 'shift';
            app.LineShift_shiftValueSpinner = uispinner(app.LineShift_shiftValue);
            app.LineShift_DetectButton = uibutton(app.GridLayout8, 'push');
            app.LineShift_DetectButton.Text = 'Detect misalignment';
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.LineShift_DirectionButtonGroup = uibuttongroup(app.GridLayout9);
            app.LineShift_DirectionButtonGroup.Title = 'Line artifact direction';
            app.LineShift_DirectionHorizontalButton = uiradiobutton(app.LineShift_DirectionButtonGroup);
            app.LineShift_DirectionHorizontalButton.Text = 'Horizontal';
            app.LineShift_DirectionHorizontalButton.Value = true;
            app.LineShift_DirectionVerticalButton = uiradiobutton(app.LineShift_DirectionButtonGroup);
            app.LineShift_DirectionVerticalButton.Text = 'Vertical';
            app.LineShift_DoCheckBox = uicheckbox(app.GridLayout9);
            app.LineShift_DoCheckBox.Text = 'Do line shift';
            app.MainPanel_EyeVideo = uipanel(app.GridLayout);
            app.MainPanel_EyeVideo.Title = 'Detect mat file of eye data to convert to mp4 video?';
            app.GridLayout4_3 = uigridlayout(app.MainPanel_EyeVideo);
            app.EyeVideo_DeflickerCheckBox = uicheckbox(app.GridLayout4_3);
            app.EyeVideo_DeflickerCheckBox.Text = 'Deflicker video ';
            app.EyeVideo_InvertCheckBox = uicheckbox(app.GridLayout4_3);
            app.EyeVideo_InvertCheckBox.Text = 'Invert brightness';
            app.EyeVideo_DoCheckBox = uicheckbox(app.GridLayout4_3);
            app.EyeVideo_DoCheckBox.Text = 'Do eye data file conversion';
            app.MainPanel_Location = uipanel(app.GridLayout);
            app.MainPanel_Location.Title = 'Select analysis output location ';
            app.GridLayout4_2 = uigridlayout(app.MainPanel_Location);
            app.LocationCheckButton = uibutton(app.GridLayout4_2, 'push');
            app.LocationCheckButton.Text = 'Check locations';
            app.LocationSetButton = uibutton(app.GridLayout4_2, 'push');
            app.LocationSetButton.Text = 'Set locations';
            app.DoLocationCheckBox = uicheckbox(app.GridLayout4_2);
            app.DoLocationCheckBox.Text = 'Save in source location';
            app.DoLocationCheckBox.Value = true;
            app.GridLayout_TitleRow = uigridlayout(app.GridLayout);
            app.LoadexistingsettingsButton = uibutton(app.GridLayout_TitleRow, 'push');
            app.LoadexistingsettingsButton.Text = 'Load existing settings';
            app.TitleLabel = uilabel(app.GridLayout_TitleRow);
            app.TitleLabel.Text = 'Set Analysis settings of Spectral Segmentation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetAnalysisSettings

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end